classdef UMACcode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        Tab                 matlab.ui.container.Tab
        Image               matlab.ui.control.Image
        z_9EditField        matlab.ui.control.NumericEditField
        z_9EditFieldLabel   matlab.ui.control.Label
        z_8EditField        matlab.ui.control.NumericEditField
        z_8EditFieldLabel   matlab.ui.control.Label
        z_7EditField        matlab.ui.control.NumericEditField
        z_7EditFieldLabel   matlab.ui.control.Label
        z_6EditField        matlab.ui.control.NumericEditField
        z_6EditFieldLabel   matlab.ui.control.Label
        z_5EditField        matlab.ui.control.NumericEditField
        z_5EditFieldLabel   matlab.ui.control.Label
        z_4EditField        matlab.ui.control.NumericEditField
        z_4EditFieldLabel   matlab.ui.control.Label
        z_3EditField        matlab.ui.control.NumericEditField
        z_3EditFieldLabel   matlab.ui.control.Label
        z_2EditField        matlab.ui.control.NumericEditField
        z_2EditFieldLabel   matlab.ui.control.Label
        z_1EditField        matlab.ui.control.NumericEditField
        z_1EditFieldLabel   matlab.ui.control.Label
        y_9EditField        matlab.ui.control.NumericEditField
        y_9EditFieldLabel   matlab.ui.control.Label
        y_8EditField        matlab.ui.control.NumericEditField
        y_8EditFieldLabel   matlab.ui.control.Label
        y_7EditField        matlab.ui.control.NumericEditField
        y_7EditFieldLabel   matlab.ui.control.Label
        y_6EditField        matlab.ui.control.NumericEditField
        y_6EditFieldLabel   matlab.ui.control.Label
        y_5EditField        matlab.ui.control.NumericEditField
        y_5EditFieldLabel   matlab.ui.control.Label
        y_4EditField        matlab.ui.control.NumericEditField
        y_4EditFieldLabel   matlab.ui.control.Label
        y_3EditField        matlab.ui.control.NumericEditField
        y_3EditFieldLabel   matlab.ui.control.Label
        y_2EditField        matlab.ui.control.NumericEditField
        y_2EditFieldLabel   matlab.ui.control.Label
        y_1EditField        matlab.ui.control.NumericEditField
        y_1EditFieldLabel   matlab.ui.control.Label
        x_9EditField        matlab.ui.control.NumericEditField
        x_9EditFieldLabel   matlab.ui.control.Label
        x_8EditField        matlab.ui.control.NumericEditField
        x_8EditFieldLabel   matlab.ui.control.Label
        x_7EditField        matlab.ui.control.NumericEditField
        x_7EditFieldLabel   matlab.ui.control.Label
        x_6EditField        matlab.ui.control.NumericEditField
        x_6EditFieldLabel   matlab.ui.control.Label
        x_5EditField        matlab.ui.control.NumericEditField
        x_5EditFieldLabel   matlab.ui.control.Label
        x_4EditField        matlab.ui.control.NumericEditField
        x_4EditFieldLabel   matlab.ui.control.Label
        x_3EditField        matlab.ui.control.NumericEditField
        x_3EditFieldLabel   matlab.ui.control.Label
        x_2EditField        matlab.ui.control.NumericEditField
        x_2EditFieldLabel   matlab.ui.control.Label
        x_1EditField        matlab.ui.control.NumericEditField
        x_1EditFieldLabel   matlab.ui.control.Label
        Label               matlab.ui.control.Label
        plotButton          matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        Tab_2               matlab.ui.container.Tab
        addButton_4         matlab.ui.control.Button
        addButton_3         matlab.ui.control.Button
        Panel_3             matlab.ui.container.Panel
        UIAxes2_2           matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        Panel_2             matlab.ui.container.Panel
        timeEditField       matlab.ui.control.NumericEditField
        timeEditFieldLabel  matlab.ui.control.Label
        R_yEditField        matlab.ui.control.NumericEditField
        R_yEditFieldLabel   matlab.ui.control.Label
        R_xEditField        matlab.ui.control.NumericEditField
        R_xEditFieldLabel   matlab.ui.control.Label
        T_zEditField        matlab.ui.control.NumericEditField
        T_zEditFieldLabel   matlab.ui.control.Label
        T_yEditField        matlab.ui.control.NumericEditField
        T_yEditFieldLabel   matlab.ui.control.Label
        T_xEditField        matlab.ui.control.NumericEditField
        T_xEditFieldLabel   matlab.ui.control.Label
        UITable             matlab.ui.control.Table
        addButton_2         matlab.ui.control.Button
    end

    
    properties (Access = private)
            x2 = zeros(1, 9);
            y2 = zeros(1, 9);
            z2 = zeros(1, 9);
         % 定义下平台的三维点
        x1_down = [100, 0, -100, 0, 0, 20, 0, -20, 0];
        y1_down = [0, -100, 0, 100, 20, 0, -20, 0, 0];
        z1_down = [0, 0, 0, 0, 0, 0, 0, 0, 0];
        data;
        newRow = {};
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)


     for idx = 1:9
    eval(['app.x2(' int2str(idx) ') = app.x_' int2str(idx) 'EditField.Value;']);
    eval(['app.y2(' int2str(idx) ') = app.y_' int2str(idx) 'EditField.Value;']);
    eval(['app.z2(' int2str(idx) ') = app.z_' int2str(idx) 'EditField.Value;']);
     end

    % 选择第一组点
x1_group = [app.x2(1), app.x2(3), app.x2(5),app.x2(7)];
y1_group = [app.y2(1), app.y2(3), app.y2(5), app.y2(7)];
z1_group = [app.z2(1), app.z2(3), app.z2(5),app.z2(7)];

% 连接首尾点
x1_group = [x1_group, x1_group(1)];
y1_group = [y1_group, y1_group(1)];
z1_group = [z1_group, z1_group(1)];

% 选择第二组点
x2_group = [app.x2(2), app.x2(4), app.x2(6), app.x2(8)];
y2_group = [app.y2(2), app.y2(4), app.y2(6), app.y2(8)];
z2_group = [app.z2(2), app.z2(4), app.z2(6), app.z2(8)];

% 连接首尾点
x2_group = [x2_group, x2_group(1)];
y2_group = [y2_group, y2_group(1)];
z2_group = [z2_group, z2_group(1)];

% 定义下平台的三维点
app.x1_down = [100, 0, -100, 0, 0, 20, 0, -20, 0];
app.y1_down = [0, -100, 0, 100, 20, 0, -20, 0, 0];
app.z1_down = [0, 0, 0, 0, 0, 0, 0, 0, 0];

% 选择下平台的第一组点
x1_down_group = [app.x1_down(1), app.x1_down(2), app.x1_down(3), app.x1_down(4)];
y1_down_group = [app.y1_down(1), app.y1_down(2), app.y1_down(3), app.y1_down(4)];
z1_down_group = [app.z1_down(1), app.z1_down(3), app.z1_down(3), app.z1_down(4)];

% 连接首尾点
x1_down_group = [x1_down_group, x1_down_group(1)];
y1_down_group = [y1_down_group, y1_down_group(1)];
z1_down_group = [z1_down_group, z1_down_group(1)];

% 选择下平台的第二组点
x2_down_group = [app.x1_down(5), app.x1_down(6), app.x1_down(7), app.x1_down(8)];
y2_down_group = [app.y1_down(5), app.y1_down(6), app.y1_down(7), app.y1_down(8)];
z2_down_group = [app.z1_down(5), app.z1_down(6), app.z1_down(7), app.z1_down(8)];

% 连接首尾点
x2_down_group = [x2_down_group, x2_down_group(1)];
y2_down_group = [y2_down_group, y2_down_group(1)];
z2_down_group = [z2_down_group, z2_down_group(1)]; 
      % 清除之前的图形
            cla(app.UIAxes);
% 绘制上平台的第一组点并用线连接
plot3(app.UIAxes,x1_group, y1_group, z1_group, '-o', 'Color', 'red', 'LineWidth', 2, 'DisplayName', 'Upper Platform Group 1');

% 绘制上平台的第二组点并用线连接
  hold(app.UIAxes, 'on');
plot3(app.UIAxes,x2_group, y2_group, z2_group, '-x', 'Color', 'blue', 'LineWidth', 2, 'DisplayName', 'Upper Platform Group 2');

% 绘制下平台的第一组点并用线连接
plot3(app.UIAxes,x1_down_group, y1_down_group, z1_down_group, '-s', 'Color', 'green', 'LineWidth', 2, 'DisplayName', 'Lower Platform Group 1');

% 绘制下平台的第二组点并用线连接
plot3(app.UIAxes,x2_down_group, y2_down_group, z2_down_group, '-d', 'Color', 'magenta', 'LineWidth', 2, 'DisplayName', 'Lower Platform Group 2');
% 增加以下部分用于在对应点之间连线
for i = 1:length(x1_group)
    % 在对应点之间连线
    plot3(app.UIAxes,[x1_group(i), x1_down_group(i)], [y1_group(i), y1_down_group(i)], [z1_group(i), z1_down_group(i)], '-.', 'Color', 'black', 'LineWidth', 1);
end
plot3(app.UIAxes,[x2_group(1), x2_down_group(2)], [y2_group(1), y2_down_group(2)], [z2_group(1), z2_down_group(2)], '-.', 'Color', 'black', 'LineWidth', 1);
plot3(app.UIAxes,[x2_group(2), x2_down_group(3)], [y2_group(2), y2_down_group(3)], [z2_group(2), z2_down_group(3)], '-.', 'Color', 'black', 'LineWidth', 1);
plot3(app.UIAxes,[x2_group(3), x2_down_group(4)], [y2_group(3), y2_down_group(4)], [z2_group(3), z2_down_group(4)], '-.', 'Color', 'black', 'LineWidth', 1);
plot3(app.UIAxes,[x2_group(4), x2_down_group(1)], [y2_group(4), y2_down_group(1)], [z2_group(4), z2_down_group(1)], '-.', 'Color', 'black', 'LineWidth', 1);
plot3(app.UIAxes,x1_group, y1_group, z1_group, '-s', 'Color', 'green', 'LineWidth', 2, 'DisplayName', 'Lower Platform Group 1');
  hold(app.UIAxes, 'on');


% 选择下平台的点（假设前四个点构成了平台的四角）
x1_group = [app.x1_down(1), app.x1_down(2), app.x1_down(3), app.x1_down(4)];
y1_group = [app.y1_down(1), app.y1_down(2), app.y1_down(3), app.y1_down(4)];
z1_group = [app.z1_down(1), app.z1_down(2), app.z1_down(3), app.z1_down(4)];

% 连接首尾点形成闭合多边形
x1_group = [x1_group, x1_group(1)];
y1_group = [y1_group, y1_group(1)];
z1_group = [z1_group, z1_group(1)];

% 定义长方体的高度
height = 100; % 长方体的高度

% 定义长方体下平面的三维点
x_rect_bottom = x1_group(1:end-1);
y_rect_bottom = y1_group(1:end-1);
z_rect_bottom = z1_group(1:end-1);

% 计算长方体上平面的三维点
x_rect_top = x_rect_bottom;
y_rect_top = y_rect_bottom;
z_rect_top = z_rect_bottom - height;

% 绘制长方体的上下表面

plot3(app.UIAxes,x1_group, y1_group, z1_group, '-s', 'Color', 'green', 'LineWidth', 2, 'DisplayName', 'Bottom Surface');

  hold(app.UIAxes, 'on');
plot3(app.UIAxes,x_rect_top, y_rect_top, z_rect_top, '-s', 'Color', 'red', 'LineWidth', 2, 'DisplayName', 'Top Surface');

% 绘制长方体的侧面边缘
for i = 1:length(x_rect_bottom)
    plot3(app.UIAxes,[x_rect_bottom(i), x_rect_top(i)], [y_rect_bottom(i), y_rect_top(i)], [z_rect_bottom(i), z_rect_top(i)], '-b', 'LineWidth', 2);
end

grid(app.UIAxes,'on');
xlabel(app.UIAxes,'X axis');
ylabel(app.UIAxes,'Y axis');
zlabel(app.UIAxes,'Z axis');

        end

        % Button pushed function: addButton_3
        function addButton_3Pushed(app, event)
            
        end

        % Button pushed function: addButton_4
        function addButton_4Pushed(app, event)
         app.data = readtable('table.xls'); 
         app.UITable.Data = app.data;
        end

        % Button pushed function: addButton_2
        function addButton_2Pushed(app, event)
         time = app.timeEditField.Value;
         Tx = app.T_xEditField.Value;
         Ty = app.T_yEditField.Value;
         Tz = app.T_zEditField.Value;
         Rx = app.R_xEditField.Value;
         Ry = app.R_yEditField.Value;
         % 创建一个表，包含从编辑框读取的值
    app.newRow = {time, Tx, Ty, Tz, Rx, Ry};

    % 获取当前表格中的数据
    currentData = app.UITable.Data;

    % 如果当前表格为空，则直接设置新行作为表格数据
    if isempty(currentData)
        app.UITable.Data = app.newRow;
    else
        % 否则追加新行到现有数据中
        app.UITable.Data = [currentData; app.newRow];
    end
    

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '坐标系数据';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'CDPR结构图')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.plotButton = uibutton(app.Tab, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = '画图';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '输入上平台坐标：';
            app.x_1EditFieldLabel = uilabel(app.Tab);
            app.x_1EditFieldLabel.Interpreter = 'latex';
            app.x_1EditFieldLabel.Text = '$x_1:$';
            app.x_1EditField = uieditfield(app.Tab, 'numeric');
            app.x_1EditField.Value = 565;
            app.x_2EditFieldLabel = uilabel(app.Tab);
            app.x_2EditFieldLabel.Interpreter = 'latex';
            app.x_2EditFieldLabel.Text = '$x_2:$';
            app.x_2EditField = uieditfield(app.Tab, 'numeric');
            app.x_2EditField.Value = 465;
            app.x_3EditFieldLabel = uilabel(app.Tab);
            app.x_3EditFieldLabel.Interpreter = 'latex';
            app.x_3EditFieldLabel.Text = '$x_3:$';
            app.x_3EditField = uieditfield(app.Tab, 'numeric');
            app.x_4EditFieldLabel = uilabel(app.Tab);
            app.x_4EditFieldLabel.Interpreter = 'latex';
            app.x_4EditFieldLabel.Text = '$x_4:$';
            app.x_4EditField = uieditfield(app.Tab, 'numeric');
            app.x_5EditFieldLabel = uilabel(app.Tab);
            app.x_5EditFieldLabel.Interpreter = 'latex';
            app.x_5EditFieldLabel.Text = '$x_5:$';
            app.x_5EditField = uieditfield(app.Tab, 'numeric');
            app.x_5EditField.Value = -565;
            app.x_6EditFieldLabel = uilabel(app.Tab);
            app.x_6EditFieldLabel.Interpreter = 'latex';
            app.x_6EditFieldLabel.Text = '$x_6:$';
            app.x_6EditField = uieditfield(app.Tab, 'numeric');
            app.x_6EditField.Value = -465;
            app.x_7EditFieldLabel = uilabel(app.Tab);
            app.x_7EditFieldLabel.Interpreter = 'latex';
            app.x_7EditFieldLabel.Text = '$x_7:$';
            app.x_7EditField = uieditfield(app.Tab, 'numeric');
            app.x_8EditFieldLabel = uilabel(app.Tab);
            app.x_8EditFieldLabel.Interpreter = 'latex';
            app.x_8EditFieldLabel.Text = '$x_8:$';
            app.x_8EditField = uieditfield(app.Tab, 'numeric');
            app.x_9EditFieldLabel = uilabel(app.Tab);
            app.x_9EditFieldLabel.Interpreter = 'latex';
            app.x_9EditFieldLabel.Text = '$x_9:$';
            app.x_9EditField = uieditfield(app.Tab, 'numeric');
            app.y_1EditFieldLabel = uilabel(app.Tab);
            app.y_1EditFieldLabel.Interpreter = 'latex';
            app.y_1EditFieldLabel.Text = '$y_1:$';
            app.y_1EditField = uieditfield(app.Tab, 'numeric');
            app.y_2EditFieldLabel = uilabel(app.Tab);
            app.y_2EditFieldLabel.Interpreter = 'latex';
            app.y_2EditFieldLabel.Text = '$y_2:$';
            app.y_2EditField = uieditfield(app.Tab, 'numeric');
            app.y_3EditFieldLabel = uilabel(app.Tab);
            app.y_3EditFieldLabel.Interpreter = 'latex';
            app.y_3EditFieldLabel.Text = '$y_3:$';
            app.y_3EditField = uieditfield(app.Tab, 'numeric');
            app.y_3EditField.Value = -525;
            app.y_4EditFieldLabel = uilabel(app.Tab);
            app.y_4EditFieldLabel.Interpreter = 'latex';
            app.y_4EditFieldLabel.Text = '$y_4:$';
            app.y_4EditField = uieditfield(app.Tab, 'numeric');
            app.y_4EditField.Value = -425;
            app.y_5EditFieldLabel = uilabel(app.Tab);
            app.y_5EditFieldLabel.Interpreter = 'latex';
            app.y_5EditFieldLabel.Text = '$y_5:$';
            app.y_5EditField = uieditfield(app.Tab, 'numeric');
            app.y_6EditFieldLabel = uilabel(app.Tab);
            app.y_6EditFieldLabel.Interpreter = 'latex';
            app.y_6EditFieldLabel.Text = '$y_6:$';
            app.y_6EditField = uieditfield(app.Tab, 'numeric');
            app.y_7EditFieldLabel = uilabel(app.Tab);
            app.y_7EditFieldLabel.Interpreter = 'latex';
            app.y_7EditFieldLabel.Text = '$y_7:$';
            app.y_7EditField = uieditfield(app.Tab, 'numeric');
            app.y_7EditField.Value = 525;
            app.y_8EditFieldLabel = uilabel(app.Tab);
            app.y_8EditFieldLabel.Interpreter = 'latex';
            app.y_8EditFieldLabel.Text = '$y_8:$';
            app.y_8EditField = uieditfield(app.Tab, 'numeric');
            app.y_8EditField.Value = 425;
            app.y_9EditFieldLabel = uilabel(app.Tab);
            app.y_9EditFieldLabel.Interpreter = 'latex';
            app.y_9EditFieldLabel.Text = '$y_9:$';
            app.y_9EditField = uieditfield(app.Tab, 'numeric');
            app.z_1EditFieldLabel = uilabel(app.Tab);
            app.z_1EditFieldLabel.Interpreter = 'latex';
            app.z_1EditFieldLabel.Text = '$z_1:$';
            app.z_1EditField = uieditfield(app.Tab, 'numeric');
            app.z_1EditField.Value = 500;
            app.z_2EditFieldLabel = uilabel(app.Tab);
            app.z_2EditFieldLabel.Interpreter = 'latex';
            app.z_2EditFieldLabel.Text = '$z_2:$';
            app.z_2EditField = uieditfield(app.Tab, 'numeric');
            app.z_2EditField.Value = 500;
            app.z_3EditFieldLabel = uilabel(app.Tab);
            app.z_3EditFieldLabel.Interpreter = 'latex';
            app.z_3EditFieldLabel.Text = '$z_3:$';
            app.z_3EditField = uieditfield(app.Tab, 'numeric');
            app.z_3EditField.Value = 500;
            app.z_4EditFieldLabel = uilabel(app.Tab);
            app.z_4EditFieldLabel.Interpreter = 'latex';
            app.z_4EditFieldLabel.Text = '$z_4:$';
            app.z_4EditField = uieditfield(app.Tab, 'numeric');
            app.z_4EditField.Value = 500;
            app.z_5EditFieldLabel = uilabel(app.Tab);
            app.z_5EditFieldLabel.Interpreter = 'latex';
            app.z_5EditFieldLabel.Text = '$z_5:$';
            app.z_5EditField = uieditfield(app.Tab, 'numeric');
            app.z_5EditField.Value = 500;
            app.z_6EditFieldLabel = uilabel(app.Tab);
            app.z_6EditFieldLabel.Interpreter = 'latex';
            app.z_6EditFieldLabel.Text = '$z_6:$';
            app.z_6EditField = uieditfield(app.Tab, 'numeric');
            app.z_6EditField.Value = 500;
            app.z_7EditFieldLabel = uilabel(app.Tab);
            app.z_7EditFieldLabel.Interpreter = 'latex';
            app.z_7EditFieldLabel.Text = '$z_7:$';
            app.z_7EditField = uieditfield(app.Tab, 'numeric');
            app.z_7EditField.Value = 500;
            app.z_8EditFieldLabel = uilabel(app.Tab);
            app.z_8EditFieldLabel.Interpreter = 'latex';
            app.z_8EditFieldLabel.Text = '$z_8:$';
            app.z_8EditField = uieditfield(app.Tab, 'numeric');
            app.z_8EditField.Value = 500;
            app.z_9EditFieldLabel = uilabel(app.Tab);
            app.z_9EditFieldLabel.Interpreter = 'latex';
            app.z_9EditFieldLabel.Text = '$z_9:$';
            app.z_9EditField = uieditfield(app.Tab, 'numeric');
            app.z_9EditField.Value = 500;
            app.Image = uiimage(app.Tab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UMACcode_resources', '20241030_102537 00_00_00-00_00_30.gif');
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '轨迹输入';
            app.addButton_2 = uibutton(app.Tab_2, 'push');
            app.addButton_2.ButtonPushedFcn = createCallbackFcn(app, @addButton_2Pushed, true);
            app.addButton_2.Tooltip = {'点击此按钮可以生成新轨迹的输入框'};
            app.addButton_2.Text = '<<添加';
            app.Panel_2 = uipanel(app.Tab_2);
            app.Panel_2.Title = '轨迹信息输入';
            app.UITable = uitable(app.Panel_2);
            app.UITable.ColumnName = {'time'; 'Tx'; 'Ty'; 'Tz'; 'Rx'; 'Ry'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.T_xEditFieldLabel = uilabel(app.Panel_2);
            app.T_xEditFieldLabel.Interpreter = 'latex';
            app.T_xEditFieldLabel.Text = '$T_x$';
            app.T_xEditField = uieditfield(app.Panel_2, 'numeric');
            app.T_yEditFieldLabel = uilabel(app.Panel_2);
            app.T_yEditFieldLabel.Interpreter = 'latex';
            app.T_yEditFieldLabel.Text = '$T_y$';
            app.T_yEditField = uieditfield(app.Panel_2, 'numeric');
            app.T_zEditFieldLabel = uilabel(app.Panel_2);
            app.T_zEditFieldLabel.Interpreter = 'latex';
            app.T_zEditFieldLabel.Text = '$T_z$';
            app.T_zEditField = uieditfield(app.Panel_2, 'numeric');
            app.R_xEditFieldLabel = uilabel(app.Panel_2);
            app.R_xEditFieldLabel.Interpreter = 'latex';
            app.R_xEditFieldLabel.Text = '$R_x$';
            app.R_xEditField = uieditfield(app.Panel_2, 'numeric');
            app.R_yEditFieldLabel = uilabel(app.Panel_2);
            app.R_yEditFieldLabel.Interpreter = 'latex';
            app.R_yEditFieldLabel.Text = '$R_y$';
            app.R_yEditField = uieditfield(app.Panel_2, 'numeric');
            app.timeEditFieldLabel = uilabel(app.Panel_2);
            app.timeEditFieldLabel.Interpreter = 'latex';
            app.timeEditFieldLabel.Text = '$time$';
            app.timeEditField = uieditfield(app.Panel_2, 'numeric');
            app.Panel_3 = uipanel(app.Tab_2);
            app.Panel_3.Title = '图像生成';
            app.UIAxes2 = uiaxes(app.Panel_3);
            title(app.UIAxes2, '三维轨迹')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2_2 = uiaxes(app.Panel_3);
            title(app.UIAxes2_2, '绳长变化')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.addButton_3 = uibutton(app.Tab_2, 'push');
            app.addButton_3.ButtonPushedFcn = createCallbackFcn(app, @addButton_3Pushed, true);
            app.addButton_3.Tooltip = {'点击此按钮可以生成新轨迹的输入框'};
            app.addButton_3.Text = '生成>>';
            app.addButton_4 = uibutton(app.Tab_2, 'push');
            app.addButton_4.ButtonPushedFcn = createCallbackFcn(app, @addButton_4Pushed, true);
            app.addButton_4.Tooltip = {'点击此按钮可以生成新轨迹的输入框'};
            app.addButton_4.Text = '读取';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UMACcode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end